classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        CloseTheAppButton      matlab.ui.control.Button
        HbEditField            matlab.ui.control.EditField
        HbEditFieldLabel       matlab.ui.control.Label
        GammaEditField         matlab.ui.control.EditField
        GammaEditFieldLabel    matlab.ui.control.Label
        LambdaEditField        matlab.ui.control.EditField
        LambdaEditFieldLabel   matlab.ui.control.Label
        TabGroup               matlab.ui.container.TabGroup
        WaterlevelandrainTab   matlab.ui.container.Tab
        HeightUIAxes           matlab.ui.control.UIAxes
        RainUIAxes             matlab.ui.control.UIAxes
        LambdacalculationTab   matlab.ui.container.Tab
        CalculateLambdaButton  matlab.ui.control.Button
        GetT1T2Button          matlab.ui.control.Button
        T2EditField            matlab.ui.control.EditField
        T2EditFieldLabel       matlab.ui.control.Label
        T1EditField            matlab.ui.control.EditField
        T1EditFieldLabel       matlab.ui.control.Label
        Dhdt2UIAxes            matlab.ui.control.UIAxes
        Height2UIAxes          matlab.ui.control.UIAxes
        GammacalculationTab    matlab.ui.container.Tab
        EstimateH_minButton    matlab.ui.control.Button
        H_min_2EditField       matlab.ui.control.EditField
        H_min_1EditField       matlab.ui.control.EditField
        H_min_2EditFieldLabel  matlab.ui.control.Label
        H_min_1EditFieldLabel  matlab.ui.control.Label
        CalculateGammaButton   matlab.ui.control.Button
        T3EditField            matlab.ui.control.EditField
        T3Label                matlab.ui.control.Label
        T4EditField            matlab.ui.control.EditField
        T4EditFieldLabel       matlab.ui.control.Label
        GetT3T4Button          matlab.ui.control.Button
        Dhdt3UIAxes            matlab.ui.control.UIAxes
        Height3UIAxes          matlab.ui.control.UIAxes
        Rain3UIAxes            matlab.ui.control.UIAxes
        PredictionTab          matlab.ui.container.Tab
        PredictheightButton    matlab.ui.control.Button
        GetT5T6Button          matlab.ui.control.Button
        T6EditField            matlab.ui.control.EditField
        T6EditFieldLabel       matlab.ui.control.Label
        T5EditField            matlab.ui.control.EditField
        T5EditFieldLabel       matlab.ui.control.Label
        Height4UIAxes          matlab.ui.control.UIAxes
        Rain4UIAxes            matlab.ui.control.UIAxes
        ShowsourceButton       matlab.ui.control.Button
        SourceEditField        matlab.ui.control.EditField
        SourceEditFieldLabel   matlab.ui.control.Label
        ImportdataButton       matlab.ui.control.Button
    end

    properties (Access = private)
        ht_m % water leve (height) of all data
        rain_mm % rain data 
        t_idx % time index (1 2 3 ... ) in unit of minute
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportdataButton
        function ImportdataButtonPushed(app, event)
            %app.SourceEditField.Value = 'Great job';
            [source_file, source_dir, filterindex] = ...
                uigetfile({'*.csv','CSV-files (*.csv)'; ...
                           '*.*',  'All Files (*.*)'}, ...
                           'Pick rail-water csv file', ...
                           'MultiSelect', 'off');
            drawnow;
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on'; 
            if source_file == 0
                return
            end
            % clear all text fields
            app.T1EditField.Value = '';
            app.T2EditField.Value = '';
            app.T3EditField.Value = '';
            app.T4EditField.Value = '';
            app.T5EditField.Value = '';
            app.T6EditField.Value = '';
            app.HbEditField.Value = '';
            app.LambdaEditField.Value = '';
            app.GammaEditField.Value = '';
            % clear all axes
            cla(app.HeightUIAxes);
            cla(app.RainUIAxes);
            cla(app.Height2UIAxes);
            cla(app.Dhdt2UIAxes);
            cla(app.Rain3UIAxes);
            cla(app.Height3UIAxes);
            cla(app.Dhdt3UIAxes);
            cla(app.Rain4UIAxes);
            cla(app.Height4UIAxes);
            % show source file
            source_full_path = [source_dir source_file];
            app.SourceEditField.Value = source_full_path;
            % show (plot) data
            ShowsourceButtonPushed(app, event)
        end

        % Button pushed function: ShowsourceButton
        function ShowsourceButtonPushed(app, event)
            % read height and rain data from file 
            filename = app.SourceEditField.Value;
            [app.t_idx, app.ht_m, app.rain_mm] = readHeightAndRain(filename);
            % show height and rain data on axes
            plotHeightAndRain2(app.t_idx, app.ht_m, app.rain_mm, app.HeightUIAxes, app.RainUIAxes);
        end

        % Button pushed function: GetT1T2Button
        function GetT1T2ButtonPushed(app, event)
            selected_xlim = xlim(app.RainUIAxes);
            t1 = int32(selected_xlim(1));
            t2 = int32(selected_xlim(2));
            app.T1EditField.Value = sprintf('%d', t1);
            app.T2EditField.Value = sprintf('%d', t2);       
        end

        % Button pushed function: GetT3T4Button
        function GetT3T4ButtonPushed(app, event)
            selected_xlim = xlim(app.RainUIAxes);
            t3 = int32(selected_xlim(1));
            t4 = int32(selected_xlim(2));
            app.T3EditField.Value = sprintf('%d', t3);
            app.T4EditField.Value = sprintf('%d', t4);                   
        end

        % Button pushed function: GetT5T6Button
        function GetT5T6ButtonPushed(app, event)
            selected_xlim = xlim(app.RainUIAxes);
            t5 = int32(selected_xlim(1));
            t6 = int32(selected_xlim(2));
            app.T5EditField.Value = sprintf('%d', t5);
            app.T6EditField.Value = sprintf('%d', t6);                               
        end

        % Callback function: CloseTheAppButton, UIFigure
        function CloseTheAppButtonPushed(app, event)
            answer = questdlg('Are you sure you want to exit?', 'Confirm action', 'Yes', 'No', 'No');
            if strcmpi(answer, 'Yes')
                app.delete();
            else
                % Just do nothing
            end
        end

        % Button pushed function: CalculateLambdaButton
        function CalculateLambdaButtonPushed(app, event)
            % calculate lambda and other information
            t1 = eval(app.T1EditField.Value);
            t2 = eval(app.T2EditField.Value);
            [lambda, hb, dhdt_t12, lambda_corrcoef, ht_simu, t_hzero, p] ... 
                = calculateLambda(t1, t2, app.ht_m);
            % display hb and lambda on the widgets
            app.HbEditField.Value = sprintf('%.24f', hb);
            app.LambdaEditField.Value = sprintf('%.24f', lambda);
            % print information on the console
            fprintf('Lambda: %f\n', lambda); 
            fprintf('hb: %f\n', hb);
            fprintf('t_hzero: %f\n', t_hzero);
            % plot height (measured vs predicted)
            cla(app.Height2UIAxes); 
            t12 = double((t1:t2) - t1);
            ht_t12 = app.ht_m(t1:t2); ht_t12 = ht_t12(:);
            plot(app.Height2UIAxes, t12 + double(t1), ht_t12, 'b:', 'LineWidth', 2); 
            grid(app.Height2UIAxes, 'on');
            hold(app.Height2UIAxes, 'on');
            plot(app.Height2UIAxes, t12 + double(t1), ht_simu, 'r', 'LineWidth', 1); 
            text(app.Height2UIAxes, 0, 0, ...
                sprintf('dh/dt = %f T + %f\nhb = %f\nlambda = %f\nCorrCoef = %f\nT(ht=0) = %f', ...
                p(1), p(2), hb, lambda, lambda_corrcoef, t_hzero), ...
                'Interpreter', 'latex', ...
                'HorizontalAlignment', 'left', ...
                'VerticalAlignment', 'bottom');
            legend(app.Height2UIAxes, 'Measured', 'Predicted');
            xlim(app.Height2UIAxes, 'auto');
            axtoolbar(app.Height2UIAxes,{'export','brush','datacursor','pan','zoomin','zoomout','restoreview'}); % enable ax toolbar after legend hiding it.
            % plot dh/dt (measured vs predicted)
            cla(app.Dhdt2UIAxes); 
            plot(app.Dhdt2UIAxes, t12 + double(t1), dhdt_t12); 
            linkaxes([app.Height2UIAxes app.Dhdt2UIAxes], 'x');
            dhdt_linear_regression = polyval(p, t12); 
            hold(app.Dhdt2UIAxes, 'on');
            plot(app.Dhdt2UIAxes, t12 + double(t1), dhdt_linear_regression, 'linewidth', 2); 
            grid(app.Dhdt2UIAxes, 'on');
            xlabel(app.Dhdt2UIAxes, sprintf('Time (min) (starting from %d)', t1));
            xlim(app.Dhdt2UIAxes, 'auto');
            legend(app.Dhdt2UIAxes, 'Measured', 'Predicted');
            axtoolbar(app.Dhdt2UIAxes,{'export','brush','datacursor','pan','zoomin','zoomout','restoreview'}); % enable ax toolbar after legend hiding it.            
        end

        % Button pushed function: CalculateGammaButton
        function CalculateGammaButtonPushed(app, event)
            % calculate gamma and other information
            t3 = int32(eval(app.T3EditField.Value));
            t4 = int32(eval(app.T4EditField.Value));
            lambda = eval(app.LambdaEditField.Value);
            hb = eval(app.HbEditField.Value);
            h_min_1 = eval(app.H_min_1EditField.Value);
            h_min_2 = eval(app.H_min_2EditField.Value);
            [gamma, ht_t34_predict, dhdt_t34, dhdt_t34_fitting] = ...
                calculateGamma(t3, t4, app.rain_mm, app.ht_m, lambda, hb, ...
                h_min_1, h_min_2);
            % display gamma on the widgets
            app.GammaEditField.Value = sprintf('%.24f', gamma);
            % print information on the screen
            fprintf('Gamma = %f\n', gamma);
            % plot rain
            cla(app.Rain3UIAxes);
            t34 = double((t3:t4) - t3); t34 = t34(:);
            ht_t34 = app.ht_m(t3:t4); ht_t34 = ht_t34(:);   
            rain_t34 = app.rain_mm(t3:t4); rain_t34 = rain_t34(:);
            plot(app.Rain3UIAxes, t34 + double(t3), rain_t34, 'b', 'LineWidth', 2); 
            grid(app.Rain3UIAxes, 'on');
            xlim(app.Rain3UIAxes, 'auto');
            axtoolbar(app.Rain3UIAxes,{'export','brush','datacursor','pan','zoomin','zoomout','restoreview'}); % enable ax toolbar after legend hiding it.            
            % plot heights (measured vs predicted)
            cla(app.Height3UIAxes);
            plot(app.Height3UIAxes, t34 + double(t3), ht_t34, 'b:', 'LineWidth', 2); 
            hold(app.Height3UIAxes, 'on');
            plot(app.Height3UIAxes, t34 + double(t3), ht_t34_predict, 'r', 'LineWidth', 1); 
            grid(app.Height3UIAxes, 'on');
            legend(app.Height3UIAxes, 'Measured', 'Fitting for gamma');
            axtoolbar(app.Height3UIAxes,{'export','brush','datacursor','pan','zoomin','zoomout','restoreview'}); % enable ax toolbar after legend hiding it.            
            xlim(app.Height3UIAxes, 'auto');
            % plot dh/dt (measured vs predicted)
            cla(app.Dhdt3UIAxes);
            plot(app.Dhdt3UIAxes, t34 + double(t3), dhdt_t34, 'b:', 'LineWidth', 2);
            hold(app.Dhdt3UIAxes, 'on');
            plot(app.Dhdt3UIAxes, t34 + double(t3), dhdt_t34_fitting, 'r', 'LineWidth', 1); 
            xlabel(app.Dhdt3UIAxes, sprintf('Time (min) (starting from %d)', t3));
            legend(app.Dhdt3UIAxes, 'Measured', 'Predicted');
            grid(app.Dhdt3UIAxes, 'on');
            axtoolbar(app.Dhdt3UIAxes,{'export','brush','datacursor','pan','zoomin','zoomout','restoreview'}); % enable ax toolbar after legend hiding it.            
            xlim(app.Dhdt3UIAxes, 'auto');
            linkaxes([app.Rain3UIAxes app.Height3UIAxes app.Dhdt3UIAxes], 'x');
        end

        % Button pushed function: PredictheightButton
        function PredictheightButtonPushed(app, event)
            % predict 
            t5 = int32(eval(app.T5EditField.Value));
            t6 = int32(eval(app.T6EditField.Value));
            lambda = eval(app.LambdaEditField.Value);
            hb = eval(app.HbEditField.Value);
            gamma = eval(app.GammaEditField.Value);
            [ht_t56_predict] = predictHeight(t5, t6, ...
                app.rain_mm, app.ht_m, lambda, hb, gamma);
            % plot rain 
            cla(app.Rain4UIAxes);
            t56 = double((t5:t6) - t5); t56 = t56(:);
            ht_t56 = app.ht_m(t5:t6); ht_t56 = ht_t56(:);   
            rain_t56 = app.rain_mm(t5:t6); rain_t56 = rain_t56(:);
            plot(app.Rain4UIAxes, t56 + double(t5), rain_t56, 'b', 'LineWidth', 2); 
            grid(app.Rain4UIAxes, 'on');
            axtoolbar(app.Rain4UIAxes,{'export','brush','datacursor','pan','zoomin','zoomout','restoreview'}); % enable ax toolbar after legend hiding it.            
            xlim(app.Rain4UIAxes, 'auto');
            % plot heights (measured vs predicted)
            cla(app.Height4UIAxes);
            plot(app.Height4UIAxes, t56 + double(t5), ht_t56, 'b:', 'LineWidth', 2); 
            hold(app.Height4UIAxes, 'on');
            plot(app.Height4UIAxes, t56 + double(t5), ht_t56_predict, 'r', 'LineWidth', 1); 
            grid(app.Height4UIAxes, 'on');
            legend(app.Height4UIAxes, 'Measured', 'Predicted');
            axtoolbar(app.Height4UIAxes,{'export','brush','datacursor','pan','zoomin','zoomout','restoreview'}); % enable ax toolbar after legend hiding it.            
            xlim(app.Height4UIAxes, 'auto');
            linkaxes([app.Rain4UIAxes app.Height4UIAxes ], 'x');
        end

        % Button pushed function: EstimateH_minButton
        function EstimateH_minButtonPushed(app, event)
            h_min_1 = prctile(app.ht_m, 50);
            h_min_2 = prctile(app.ht_m, 85);
            app.H_min_1EditField.Value = sprintf('%f', h_min_1);
            app.H_min_2EditField.Value = sprintf('%f', h_min_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseTheAppButtonPushed, true);
            app.ImportdataButton = uibutton(app.UIFigure, 'push');
            app.ImportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportdataButtonPushed, true);
            app.ImportdataButton.Text = 'Import data';
            app.SourceEditFieldLabel = uilabel(app.UIFigure);
            app.SourceEditFieldLabel.Text = 'Source:';
            app.SourceEditField = uieditfield(app.UIFigure, 'text');
            app.ShowsourceButton = uibutton(app.UIFigure, 'push');
            app.ShowsourceButton.ButtonPushedFcn = createCallbackFcn(app, @ShowsourceButtonPushed, true);
            app.ShowsourceButton.Text = 'Show source';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WaterlevelandrainTab = uitab(app.TabGroup);
            app.WaterlevelandrainTab.Title = 'Water level and rain';
            app.RainUIAxes = uiaxes(app.WaterlevelandrainTab);
            title(app.RainUIAxes, 'Rain')
            xlabel(app.RainUIAxes, 'X')
            ylabel(app.RainUIAxes, 'Y')
            zlabel(app.RainUIAxes, 'Z')
            app.HeightUIAxes = uiaxes(app.WaterlevelandrainTab);
            title(app.HeightUIAxes, 'Height')
            xlabel(app.HeightUIAxes, 'X')
            ylabel(app.HeightUIAxes, 'Y')
            zlabel(app.HeightUIAxes, 'Z')
            app.LambdacalculationTab = uitab(app.TabGroup);
            app.LambdacalculationTab.Title = 'Lambda calculation';
            app.Height2UIAxes = uiaxes(app.LambdacalculationTab);
            title(app.Height2UIAxes, 'Height (water level) (m)')
            xlabel(app.Height2UIAxes, 'X')
            ylabel(app.Height2UIAxes, 'Height (water level) m')
            zlabel(app.Height2UIAxes, 'Z')
            app.Dhdt2UIAxes = uiaxes(app.LambdacalculationTab);
            title(app.Dhdt2UIAxes, 'dH/dt')
            xlabel(app.Dhdt2UIAxes, 'X')
            ylabel(app.Dhdt2UIAxes, 'dH/dt (m/min)')
            zlabel(app.Dhdt2UIAxes, 'Z')
            app.T1EditFieldLabel = uilabel(app.LambdacalculationTab);
            app.T1EditFieldLabel.Text = 'T1';
            app.T1EditField = uieditfield(app.LambdacalculationTab, 'text');
            app.T2EditFieldLabel = uilabel(app.LambdacalculationTab);
            app.T2EditFieldLabel.Text = 'T2';
            app.T2EditField = uieditfield(app.LambdacalculationTab, 'text');
            app.GetT1T2Button = uibutton(app.LambdacalculationTab, 'push');
            app.GetT1T2Button.ButtonPushedFcn = createCallbackFcn(app, @GetT1T2ButtonPushed, true);
            app.GetT1T2Button.Text = 'Get T1 & T2';
            app.CalculateLambdaButton = uibutton(app.LambdacalculationTab, 'push');
            app.CalculateLambdaButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateLambdaButtonPushed, true);
            app.CalculateLambdaButton.Text = 'Calculate Lambda';
            app.GammacalculationTab = uitab(app.TabGroup);
            app.GammacalculationTab.Title = 'Gamma calculation';
            app.Rain3UIAxes = uiaxes(app.GammacalculationTab);
            title(app.Rain3UIAxes, 'Rainfall (mm/min)')
            xlabel(app.Rain3UIAxes, 'X')
            ylabel(app.Rain3UIAxes, 'Y')
            zlabel(app.Rain3UIAxes, 'Z')
            app.Height3UIAxes = uiaxes(app.GammacalculationTab);
            title(app.Height3UIAxes, 'Height (water level) (mm)')
            xlabel(app.Height3UIAxes, 'X')
            ylabel(app.Height3UIAxes, 'Y')
            zlabel(app.Height3UIAxes, 'Z')
            app.Dhdt3UIAxes = uiaxes(app.GammacalculationTab);
            title(app.Dhdt3UIAxes, 'dh/dt (mm/min)')
            xlabel(app.Dhdt3UIAxes, 'X')
            ylabel(app.Dhdt3UIAxes, 'Y')
            zlabel(app.Dhdt3UIAxes, 'Z')
            app.GetT3T4Button = uibutton(app.GammacalculationTab, 'push');
            app.GetT3T4Button.ButtonPushedFcn = createCallbackFcn(app, @GetT3T4ButtonPushed, true);
            app.GetT3T4Button.Text = 'Get T3 & T4';
            app.T4EditFieldLabel = uilabel(app.GammacalculationTab);
            app.T4EditFieldLabel.Text = 'T4';
            app.T4EditField = uieditfield(app.GammacalculationTab, 'text');
            app.T3Label = uilabel(app.GammacalculationTab);
            app.T3Label.Text = 'T3';
            app.T3EditField = uieditfield(app.GammacalculationTab, 'text');
            app.CalculateGammaButton = uibutton(app.GammacalculationTab, 'push');
            app.CalculateGammaButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateGammaButtonPushed, true);
            app.CalculateGammaButton.Text = 'Calculate Gamma';
            app.H_min_1EditFieldLabel = uilabel(app.GammacalculationTab);
            app.H_min_1EditFieldLabel.Text = 'H_min_1';
            app.H_min_2EditFieldLabel = uilabel(app.GammacalculationTab);
            app.H_min_2EditFieldLabel.Text = 'H_min_2';
            app.H_min_1EditField = uieditfield(app.GammacalculationTab, 'text');
            app.H_min_2EditField = uieditfield(app.GammacalculationTab, 'text');
            app.EstimateH_minButton = uibutton(app.GammacalculationTab, 'push');
            app.EstimateH_minButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateH_minButtonPushed, true);
            app.EstimateH_minButton.Text = 'Estimate H_min';
            app.PredictionTab = uitab(app.TabGroup);
            app.PredictionTab.Title = 'Prediction';
            app.Rain4UIAxes = uiaxes(app.PredictionTab);
            title(app.Rain4UIAxes, 'Rainfall (mm/min)')
            xlabel(app.Rain4UIAxes, 'X')
            ylabel(app.Rain4UIAxes, 'Y')
            zlabel(app.Rain4UIAxes, 'Z')
            app.Height4UIAxes = uiaxes(app.PredictionTab);
            title(app.Height4UIAxes, 'Height (water level) (mm)')
            xlabel(app.Height4UIAxes, 'X')
            ylabel(app.Height4UIAxes, 'Y')
            zlabel(app.Height4UIAxes, 'Z')
            app.T5EditFieldLabel = uilabel(app.PredictionTab);
            app.T5EditFieldLabel.Text = 'T5';
            app.T5EditField = uieditfield(app.PredictionTab, 'text');
            app.T6EditFieldLabel = uilabel(app.PredictionTab);
            app.T6EditFieldLabel.Text = 'T6';
            app.T6EditField = uieditfield(app.PredictionTab, 'text');
            app.GetT5T6Button = uibutton(app.PredictionTab, 'push');
            app.GetT5T6Button.ButtonPushedFcn = createCallbackFcn(app, @GetT5T6ButtonPushed, true);
            app.GetT5T6Button.Text = 'Get T5 & T6';
            app.PredictheightButton = uibutton(app.PredictionTab, 'push');
            app.PredictheightButton.ButtonPushedFcn = createCallbackFcn(app, @PredictheightButtonPushed, true);
            app.PredictheightButton.Text = 'Predict height';
            app.LambdaEditFieldLabel = uilabel(app.UIFigure);
            app.LambdaEditFieldLabel.Text = 'Lambda';
            app.LambdaEditField = uieditfield(app.UIFigure, 'text');
            app.GammaEditFieldLabel = uilabel(app.UIFigure);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.UIFigure, 'text');
            app.HbEditFieldLabel = uilabel(app.UIFigure);
            app.HbEditFieldLabel.Text = 'Hb';
            app.HbEditField = uieditfield(app.UIFigure, 'text');
            app.CloseTheAppButton = uibutton(app.UIFigure, 'push');
            app.CloseTheAppButton.ButtonPushedFcn = createCallbackFcn(app, @CloseTheAppButtonPushed, true);
            app.CloseTheAppButton.Text = 'Close The App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end